classdef BrainRecordIR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BrainRecordIRUIFigure          matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        RegisterNewSubjectMenu         matlab.ui.container.Menu
        LoadSavedDataMenu              matlab.ui.container.Menu
        PreferencesMenu                matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        DataQualityMenu                matlab.ui.container.Menu
        ShowQualityMenu                matlab.ui.container.Menu
        QuickSetupMenu                 matlab.ui.container.Menu
        RegisterProbe                  matlab.ui.container.Menu
        LaunchPheobeMenu               matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        HelpDocumentionMenu            matlab.ui.container.Menu
        Battery100Label                matlab.ui.control.Label
        NotConnectedLamp               matlab.ui.control.Lamp
        NotConnectedLampLabel          matlab.ui.control.Label
        TabGroupControls               matlab.ui.container.TabGroup
        DetectorsTab                   matlab.ui.container.Tab
        ShowNoiseMapButton             matlab.ui.control.Button
        TabGroupDetectors              matlab.ui.container.TabGroup
        Tab1_8                         matlab.ui.container.Tab
        SetAllGainEditField            matlab.ui.control.NumericEditField
        SetAllGainEditFieldLabel       matlab.ui.control.Label
        PanelD_8                       matlab.ui.container.Panel
        EditFieldD_8                   matlab.ui.control.NumericEditField
        SliderD_8                      matlab.ui.control.Slider
        LampD_8                        matlab.ui.control.Lamp
        PanelD_7                       matlab.ui.container.Panel
        EditFieldD_7                   matlab.ui.control.NumericEditField
        SliderD_7                      matlab.ui.control.Slider
        LampD_7                        matlab.ui.control.Lamp
        PanelD_6                       matlab.ui.container.Panel
        EditFieldD_6                   matlab.ui.control.NumericEditField
        SliderD_6                      matlab.ui.control.Slider
        LampD_6                        matlab.ui.control.Lamp
        PanelD_5                       matlab.ui.container.Panel
        EditFieldD_5                   matlab.ui.control.NumericEditField
        SliderD_5                      matlab.ui.control.Slider
        LampD_5                        matlab.ui.control.Lamp
        PanelD_4                       matlab.ui.container.Panel
        EditFieldD_4                   matlab.ui.control.NumericEditField
        SliderD_4                      matlab.ui.control.Slider
        LampD_4                        matlab.ui.control.Lamp
        PanelD_3                       matlab.ui.container.Panel
        EditFieldD_3                   matlab.ui.control.NumericEditField
        SliderD_3                      matlab.ui.control.Slider
        LampD_3                        matlab.ui.control.Lamp
        PanelD_2                       matlab.ui.container.Panel
        EditFieldD_2                   matlab.ui.control.NumericEditField
        SliderD_2                      matlab.ui.control.Slider
        LampD_2                        matlab.ui.control.Lamp
        PanelD_1                       matlab.ui.container.Panel
        EditFieldD_1                   matlab.ui.control.NumericEditField
        SliderD_1                      matlab.ui.control.Slider
        LampD_1                        matlab.ui.control.Lamp
        Tab9_16                        matlab.ui.container.Tab
        PanelD_16                      matlab.ui.container.Panel
        EditFieldD_16                  matlab.ui.control.NumericEditField
        SliderD_16                     matlab.ui.control.Slider
        LampD_16                       matlab.ui.control.Lamp
        PanelD_15                      matlab.ui.container.Panel
        EditFieldD_15                  matlab.ui.control.NumericEditField
        SliderD_15                     matlab.ui.control.Slider
        LampD_15                       matlab.ui.control.Lamp
        PanelD_14                      matlab.ui.container.Panel
        EditFieldD_14                  matlab.ui.control.NumericEditField
        SliderD_14                     matlab.ui.control.Slider
        LampD_14                       matlab.ui.control.Lamp
        PanelD_13                      matlab.ui.container.Panel
        EditFieldD_13                  matlab.ui.control.NumericEditField
        SliderD_13                     matlab.ui.control.Slider
        LampD_13                       matlab.ui.control.Lamp
        PanelD_12                      matlab.ui.container.Panel
        EditFieldD_12                  matlab.ui.control.NumericEditField
        SliderD_12                     matlab.ui.control.Slider
        LampD_12                       matlab.ui.control.Lamp
        PanelD_11                      matlab.ui.container.Panel
        EditFieldD_11                  matlab.ui.control.NumericEditField
        SliderD_11                     matlab.ui.control.Slider
        LampD_11                       matlab.ui.control.Lamp
        PanelD_10                      matlab.ui.container.Panel
        EditFieldD_10                  matlab.ui.control.NumericEditField
        SliderD_10                     matlab.ui.control.Slider
        LampD_10                       matlab.ui.control.Lamp
        PanelD_9                       matlab.ui.container.Panel
        EditFieldD_9                   matlab.ui.control.NumericEditField
        SliderD_9                      matlab.ui.control.Slider
        LampD_9                        matlab.ui.control.Lamp
        Tab17_24                       matlab.ui.container.Tab
        PanelD_24                      matlab.ui.container.Panel
        EditFieldD_24                  matlab.ui.control.NumericEditField
        SliderD_24                     matlab.ui.control.Slider
        LampD_24                       matlab.ui.control.Lamp
        PanelD_23                      matlab.ui.container.Panel
        EditFieldD_23                  matlab.ui.control.NumericEditField
        SliderD_23                     matlab.ui.control.Slider
        LampD_23                       matlab.ui.control.Lamp
        PanelD_22                      matlab.ui.container.Panel
        EditFieldD_22                  matlab.ui.control.NumericEditField
        SliderD_22                     matlab.ui.control.Slider
        LampD_22                       matlab.ui.control.Lamp
        PanelD_21                      matlab.ui.container.Panel
        EditFieldD_21                  matlab.ui.control.NumericEditField
        SliderD_21                     matlab.ui.control.Slider
        LampD_21                       matlab.ui.control.Lamp
        PanelD_20                      matlab.ui.container.Panel
        EditFieldD_20                  matlab.ui.control.NumericEditField
        SliderD_20                     matlab.ui.control.Slider
        LampD_20                       matlab.ui.control.Lamp
        PanelD_19                      matlab.ui.container.Panel
        EditFieldD_19                  matlab.ui.control.NumericEditField
        SliderD_19                     matlab.ui.control.Slider
        LampD_19                       matlab.ui.control.Lamp
        PanelD_18                      matlab.ui.container.Panel
        EditFieldD_18                  matlab.ui.control.NumericEditField
        SliderD_18                     matlab.ui.control.Slider
        LampD_18                       matlab.ui.control.Lamp
        PanelD_17                      matlab.ui.container.Panel
        EditFieldD_17                  matlab.ui.control.NumericEditField
        SliderD_17                     matlab.ui.control.Slider
        LampD_17                       matlab.ui.control.Lamp
        Tab25_32                       matlab.ui.container.Tab
        PanelD_32                      matlab.ui.container.Panel
        EditFieldD_32                  matlab.ui.control.NumericEditField
        SliderD_32                     matlab.ui.control.Slider
        LampD_32                       matlab.ui.control.Lamp
        PanelD_31                      matlab.ui.container.Panel
        EditFieldD_31                  matlab.ui.control.NumericEditField
        SliderD_31                     matlab.ui.control.Slider
        LampD_31                       matlab.ui.control.Lamp
        PanelD_30                      matlab.ui.container.Panel
        EditFieldD_30                  matlab.ui.control.NumericEditField
        SliderD_30                     matlab.ui.control.Slider
        LampD_30                       matlab.ui.control.Lamp
        PanelD_29                      matlab.ui.container.Panel
        EditFieldD_29                  matlab.ui.control.NumericEditField
        SliderD_29                     matlab.ui.control.Slider
        LampD_29                       matlab.ui.control.Lamp
        PanelD_28                      matlab.ui.container.Panel
        EditFieldD_28                  matlab.ui.control.NumericEditField
        SliderD_28                     matlab.ui.control.Slider
        LampD_28                       matlab.ui.control.Lamp
        PanelD_27                      matlab.ui.container.Panel
        EditFieldD_27                  matlab.ui.control.NumericEditField
        SliderD_27                     matlab.ui.control.Slider
        LampD_27                       matlab.ui.control.Lamp
        PanelD_26                      matlab.ui.container.Panel
        EditFieldD_26                  matlab.ui.control.NumericEditField
        SliderD_26                     matlab.ui.control.Slider
        LampD_26                       matlab.ui.control.Lamp
        PanelD_25                      matlab.ui.container.Panel
        EditFieldD_25                  matlab.ui.control.NumericEditField
        SliderD_25                     matlab.ui.control.Slider
        LampD_25                       matlab.ui.control.Lamp
        AutoGainButton                 matlab.ui.control.Button
        SourcesTab                     matlab.ui.container.Tab
        SetAllPowersEditField          matlab.ui.control.NumericEditField
        SetAllPowersEditFieldLabel     matlab.ui.control.Label
        LinkWavelengthsCheckBox        matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Src4Panel                      matlab.ui.container.Panel
        LampS_8                        matlab.ui.control.Lamp
        LampS_7                        matlab.ui.control.Lamp
        SwitchS_8                      matlab.ui.control.RockerSwitch
        SwitchS_7                      matlab.ui.control.RockerSwitch
        SpinnerS_8                     matlab.ui.control.Spinner
        SpinnerS_7                     matlab.ui.control.Spinner
        Src3Panel                      matlab.ui.container.Panel
        LampS_6                        matlab.ui.control.Lamp
        LampS_5                        matlab.ui.control.Lamp
        SwitchS_6                      matlab.ui.control.RockerSwitch
        SwitchS_5                      matlab.ui.control.RockerSwitch
        SpinnerS_6                     matlab.ui.control.Spinner
        SpinnerS_5                     matlab.ui.control.Spinner
        Src2Panel                      matlab.ui.container.Panel
        LampS_4                        matlab.ui.control.Lamp
        LampS_3                        matlab.ui.control.Lamp
        SwitchS_4                      matlab.ui.control.RockerSwitch
        SwitchS_3                      matlab.ui.control.RockerSwitch
        SpinnerS_4                     matlab.ui.control.Spinner
        SpinnerS_3                     matlab.ui.control.Spinner
        Src1Panel                      matlab.ui.container.Panel
        LampS_2                        matlab.ui.control.Lamp
        LampS_1                        matlab.ui.control.Lamp
        SwitchS_2                      matlab.ui.control.RockerSwitch
        SwitchS_1                      matlab.ui.control.RockerSwitch
        SpinnerS_2                     matlab.ui.control.Spinner
        SpinnerS_1                     matlab.ui.control.Spinner
        Tab_2                          matlab.ui.container.Tab
        Src8Panel                      matlab.ui.container.Panel
        LampS_16                       matlab.ui.control.Lamp
        LampS_15                       matlab.ui.control.Lamp
        SwitchS_16                     matlab.ui.control.RockerSwitch
        SwitchS_15                     matlab.ui.control.RockerSwitch
        SpinnerS_16                    matlab.ui.control.Spinner
        SpinnerS_15                    matlab.ui.control.Spinner
        Src7Panel                      matlab.ui.container.Panel
        LampS_14                       matlab.ui.control.Lamp
        LampS_13                       matlab.ui.control.Lamp
        SwitchS_14                     matlab.ui.control.RockerSwitch
        SwitchS_13                     matlab.ui.control.RockerSwitch
        SpinnerS_14                    matlab.ui.control.Spinner
        SpinnerS_13                    matlab.ui.control.Spinner
        Src6Panel                      matlab.ui.container.Panel
        LampS_12                       matlab.ui.control.Lamp
        LampS_11                       matlab.ui.control.Lamp
        SwitchS_12                     matlab.ui.control.RockerSwitch
        SwitchS_11                     matlab.ui.control.RockerSwitch
        SpinnerS_12                    matlab.ui.control.Spinner
        SpinnerS_11                    matlab.ui.control.Spinner
        Src5Panel                      matlab.ui.container.Panel
        LampS_10                       matlab.ui.control.Lamp
        LampS_9                        matlab.ui.control.Lamp
        SwitchS_10                     matlab.ui.control.RockerSwitch
        SwitchS_9                      matlab.ui.control.RockerSwitch
        SpinnerS_10                    matlab.ui.control.Spinner
        SpinnerS_9                     matlab.ui.control.Spinner
        Tab_3                          matlab.ui.container.Tab
        Src12Panel                     matlab.ui.container.Panel
        LampS_24                       matlab.ui.control.Lamp
        LampS_23                       matlab.ui.control.Lamp
        SwitchS_24                     matlab.ui.control.RockerSwitch
        SwitchS_23                     matlab.ui.control.RockerSwitch
        SpinnerS_24                    matlab.ui.control.Spinner
        SpinnerS_23                    matlab.ui.control.Spinner
        Src11Panel                     matlab.ui.container.Panel
        LampS_22                       matlab.ui.control.Lamp
        LampS_21                       matlab.ui.control.Lamp
        SwitchS_22                     matlab.ui.control.RockerSwitch
        SwitchS_21                     matlab.ui.control.RockerSwitch
        SpinnerS_22                    matlab.ui.control.Spinner
        SpinnerS_21                    matlab.ui.control.Spinner
        Src10Panel                     matlab.ui.container.Panel
        LampS_20                       matlab.ui.control.Lamp
        LampS_19                       matlab.ui.control.Lamp
        SwitchS_20                     matlab.ui.control.RockerSwitch
        SwitchS_19                     matlab.ui.control.RockerSwitch
        SpinnerS_20                    matlab.ui.control.Spinner
        SpinnerS_19                    matlab.ui.control.Spinner
        Src9Panel                      matlab.ui.container.Panel
        LampS_18                       matlab.ui.control.Lamp
        LampS_17                       matlab.ui.control.Lamp
        SwitchS_18                     matlab.ui.control.RockerSwitch
        SwitchS_17                     matlab.ui.control.RockerSwitch
        SpinnerS_18                    matlab.ui.control.Spinner
        SpinnerS_17                    matlab.ui.control.Spinner
        Tab_4                          matlab.ui.container.Tab
        Src16Panel                     matlab.ui.container.Panel
        LampS_32                       matlab.ui.control.Lamp
        LampS_31                       matlab.ui.control.Lamp
        SwitchS_32                     matlab.ui.control.RockerSwitch
        SwitchS_31                     matlab.ui.control.RockerSwitch
        SpinnerS_32                    matlab.ui.control.Spinner
        SpinnerS_31                    matlab.ui.control.Spinner
        Src15Panel                     matlab.ui.container.Panel
        LampS_30                       matlab.ui.control.Lamp
        LampS_29                       matlab.ui.control.Lamp
        SwitchS_30                     matlab.ui.control.RockerSwitch
        SwitchS_29                     matlab.ui.control.RockerSwitch
        SpinnerS_30                    matlab.ui.control.Spinner
        SpinnerS_29                    matlab.ui.control.Spinner
        Src14Panel                     matlab.ui.container.Panel
        LampS_28                       matlab.ui.control.Lamp
        LampS_27                       matlab.ui.control.Lamp
        SwitchS_28                     matlab.ui.control.RockerSwitch
        SwitchS_27                     matlab.ui.control.RockerSwitch
        SpinnerS_28                    matlab.ui.control.Spinner
        SpinnerS_27                    matlab.ui.control.Spinner
        Src13Panel                     matlab.ui.container.Panel
        LampS_26                       matlab.ui.control.Lamp
        LampS_25                       matlab.ui.control.Lamp
        SwitchS_26                     matlab.ui.control.RockerSwitch
        SwitchS_25                     matlab.ui.control.RockerSwitch
        SpinnerS_26                    matlab.ui.control.Spinner
        SpinnerS_25                    matlab.ui.control.Spinner
        AutoAdjustButton               matlab.ui.control.Button
        EventsTab                      matlab.ui.container.Tab
        AuxTriggersPanel               matlab.ui.container.Panel
        Aux1Lamp_11                    matlab.ui.control.Lamp
        Aux1Lamp_10                    matlab.ui.control.Lamp
        Aux1Lamp_9                     matlab.ui.control.Lamp
        Aux1Lamp_8                     matlab.ui.control.Lamp
        Aux1Lamp_7                     matlab.ui.control.Lamp
        Aux1Lamp_6                     matlab.ui.control.Lamp
        Aux1Lamp_5                     matlab.ui.control.Lamp
        Aux1Lamp_4                     matlab.ui.control.Lamp
        Panel_9                        matlab.ui.container.Panel
        NumberofEventsLabel            matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        TaskDropDown                   matlab.ui.control.DropDown
        Switch_33                      matlab.ui.control.RockerSwitch
        MarkEventButton_2              matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        RealtimeTab                    matlab.ui.container.Tab
        ApplyMotionCorrectionCheckBox  matlab.ui.control.CheckBox
        LinearModelPanel               matlab.ui.container.Panel
        TuneSlider                     matlab.ui.control.Slider
        TuneSliderLabel                matlab.ui.control.Label
        ApplyGLMCheckBox               matlab.ui.control.CheckBox
        ModelDropDown                  matlab.ui.control.DropDown
        ModelDropDownLabel             matlab.ui.control.Label
        MotionCorrectionPanel          matlab.ui.container.Panel
        FilteringPanel                 matlab.ui.container.Panel
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox                       matlab.ui.control.CheckBox
        LowPassEditField               matlab.ui.control.NumericEditField
        LowPassEditFieldLabel          matlab.ui.control.Label
        HighPassEditField              matlab.ui.control.NumericEditField
        HighPassEditFieldLabel         matlab.ui.control.Label
        FilesTab                       matlab.ui.container.Tab
        RunAnalysisButton              matlab.ui.control.Button
        Tree                           matlab.ui.container.Tree
        DataNode                       matlab.ui.container.TreeNode
        RecordedFilesNode              matlab.ui.container.TreeNode
        LoadedFilesNode                matlab.ui.container.TreeNode
        AnalyzedResultsNode            matlab.ui.container.TreeNode
        SystemTab                      matlab.ui.container.Tab
        ConnectionEditField            matlab.ui.control.EditField
        ConnectionEditFieldLabel       matlab.ui.control.Label
        SystemMessageTextArea          matlab.ui.control.TextArea
        SystemMessageTextAreaLabel     matlab.ui.control.Label
        ApplyHardwarefiltersCheckBox   matlab.ui.control.CheckBox
        SampleRateDropDown             matlab.ui.control.DropDown
        SampleRateDropDownLabel        matlab.ui.control.Label
        DrawMode4Probe                 matlab.ui.control.DropDown
        DrawModeDropDownLabel          matlab.ui.control.Label
        TabViewGrou                    matlab.ui.container.TabGroup
        ResearchViewTab                matlab.ui.container.Tab
        Raw690nmunfilteredLabel        matlab.ui.control.Label
        AutoscaleYaxisCheckBox         matlab.ui.control.CheckBox
        WindowDataCheckBox             matlab.ui.control.CheckBox
        WindowDataEditField            matlab.ui.control.NumericEditField
        AquistionControlsPanel         matlab.ui.container.Panel
        AddCommentButton               matlab.ui.control.Button
        MarkEventButton                matlab.ui.control.Button
        SwitchSourcesOnOff             matlab.ui.control.RockerSwitch
        SourcesLamp                    matlab.ui.control.Lamp
        SourcesLabel                   matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        Progressbar                    matlab.ui.control.UIAxes
        SelectDisplayType              matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        MainPlotWindow                 matlab.ui.control.UIAxes
        ClinicalViewTab                matlab.ui.container.Tab
        DefineROIsButton               matlab.ui.control.Button
        HeartRateEditField             matlab.ui.control.NumericEditField
        HeartRateEditFieldLabel        matlab.ui.control.Label
        LeftSO2EditField               matlab.ui.control.NumericEditField
        LeftSO2EditFieldLabel          matlab.ui.control.Label
        RightSO2EditField              matlab.ui.control.NumericEditField
        RightSO2EditFieldLabel         matlab.ui.control.Label
        ClinicalImageAxis              matlab.ui.control.UIAxes
        UIAxesClinicalView             matlab.ui.control.UIAxes
        StatisticsViewTab              matlab.ui.container.Tab
        DViewDropDownStats3DViewAngle  matlab.ui.control.DropDown
        DViewDropDownLabel             matlab.ui.control.Label
        DrawMode4StatsView             matlab.ui.control.DropDown
        DrawModeDropDownLabel_2        matlab.ui.control.Label
        DropDownStatsPorQ              matlab.ui.control.DropDown
        ThreshholdEditField            matlab.ui.control.NumericEditField
        ThreshholdEditFieldLabel       matlab.ui.control.Label
        ListBoxWhichContrast           matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
        UIAxesStatsViewPlot            matlab.ui.control.UIAxes
        SDGPlotWindow                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SystemType = "SIMULATOR"; % Instrument type
        Device; % Control class for system
        UpdateTime=500; % ms to update display
        handles; % struct to hold handles to app controls
        Subject % Holds data
        Folders % default folder locations
        Drawing % holds the drawing handles
        MainTimer % holds drawing and data collection timer;
        Realtime % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, systemType, datafolder)
            disp("App");
            disp(systemType);
            disp(datafolder);
            disp(nargin);
            if(nargin<2)
                systemType=[];
            end
            datafolder = [systemType '_Data'];
            projectRoot = fileparts(mfilename('fullpath'));
            
            if(nargin>2 && ~isempty(datafolder))
                if(ismac | isunix)
                    system.Folders.DefaultData = [projectRoot '/../' datafolder];
                else
                    system.Folders.DefaultData = [projectRoot '\..\' datafolder];
                end
            else
                datafolder=[];
            end
            
            disp(datafolder)
            
            set(app.BrainRecordIRUIFigure,'WindowState','minimized');
            set(app.BrainRecordIRUIFigure,'visible','off');
            
            % make sure the matlab path is ok
            folder=fileparts(which('BrainRecordIR.mlapp'));
            if(~contains(path,fullfile(folder,'src')))
                disp('Adding BrainRecordIR files to path');
                path(path,genpath(folder));
            end
            s = SplashScreen( 'TechEn', 'splash.png');
            initializeApp(app,systemType, datafolder);
            pause(1);
            set(app.BrainRecordIRUIFigure,'WindowState','maximized');
            set(app.BrainRecordIRUIFigure,'visible','on');
            delete(s);
            
            if(app.Device.isconnected)
                set(app.NotConnectedLamp,'Color','g');
                set(app.NotConnectedLampLabel,'Text',app.Device.info);
            else
                set(app.NotConnectedLamp,'Color',[0.902 0.902 0.902]);
                set(app.NotConnectedLampLabel,'Text','Not Connected');
            end
            set(app.Battery100Label,'Text',['Battery=' num2str(app.Device.battery) '%']);


        end

        % Menu selected function: RegisterNewSubjectMenu
        function RegisterNewSubjectMenuSelected(app, event)
            RegisterSubject;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
           %display the message box about the software
           msgbox({'Brain RecordIR Software',...
               'Ted Huppert','University of Pittsburgh','Copyright 2018'},...
               'About');
           
        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            StartDAQ();
        end

        % Value changed function: SwitchSourcesOnOff
        function SwitchSourcesOnOffValueChanged(app, event)
            LasersAllOnOff;
            
        end

        % Value changed function: SelectDisplayType
        function SelectDisplayTypeValueChanged(app, event)
            SelectWhichData;
            
        end

        % Menu selected function: LoadSavedDataMenu
        function LoadSavedDataMenuSelected(app, event)
            LoadPrevious;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            ShutdownNIRS;
            delete(app);
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            
        end

        % Menu selected function: QuickSetupMenu
        function QuickSetupMenuSelected(app, event)
            
        end

        % Menu selected function: ShowQualityMenu
        function ShowQualityMenuSelected(app, event)
            
        end

        % Menu selected function: HelpDocumentionMenu
        function HelpDocumentionMenuSelected(app, event)
            
        end

        % Button pushed function: MarkEventButton, MarkEventButton_2
        function MarkEventButton_2Pushed(app, event)
            AddStimEvent;
        end

        % Value changed function: Switch_33
        function Switch_33ValueChanged(app, event)
            EventToggle;
            
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            
        end

        % Button pushed function: AddCommentButton
        function AddCommentButtonPushed(app, event)
            AddStimComment;
        end

        % Button pushed function: AutoAdjustButton
        function AutoAdjustButtonPushed(app, event)
            LaserAutoAdjust;
        end

        % Button pushed function: AutoGainButton
        function AutoGainButtonPushed(app, event)
            DetAutoAdjust;
        end

        % Button pushed function: ShowNoiseMapButton
        function ShowNoiseMapButtonPushed(app, event)
            ShowSNRDetectors;
        end

        % Value changed function: HighPassEditField
        function HighPassEditFieldValueChanged(app, event)
            BPFchanged;
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            BPFchanged;
            
        end

        % Value changed function: LowPassEditField
        function LowPassEditFieldValueChanged(app, event)
            BPFchanged;
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            BPFchanged;
            
        end

        % Value changed function: ApplyMotionCorrectionCheckBox
        function ApplyMotionCorrectionCheckBoxValueChanged(app, event)
           MOCOchanged;
            
        end

        % Value changed function: ApplyGLMCheckBox
        function ApplyGLMCheckBoxValueChanged(app, event)
            GLMchanged;
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            PostAnalysis;
        end

        % Value changed function: DrawMode4Probe
        function DrawMode4ProbeValueChanged(app, event)
            ChangeProbeView;
            
        end

        % Value changed function: LinkWavelengthsCheckBox
        function LinkWavelengthsCheckBoxValueChanged(app, event)
            
        end

        % Close request function: BrainRecordIRUIFigure
        function BrainRecordIRUIFigureCloseRequest(app, event)
            ShutdownNIRS;
            delete(app)
            
        end

        % Value changed function: DrawMode4StatsView
        function DrawMode4StatsViewValueChanged(app, event)
            ChangeProbeViewStats;
            
        end

        % Value changed function: ListBoxWhichContrast
        function ListBoxWhichContrastValueChanged(app, event)
             ChangeProbeViewStats;
            
        end

        % Value changed function: ThreshholdEditField
        function ThreshholdEditFieldValueChanged(app, event)
             ChangeProbeViewStats;
            
        end

        % Value changed function: DropDownStatsPorQ
        function DropDownStatsPorQValueChanged(app, event)
             ChangeProbeViewStats;
            
        end

        % Value changed function: DViewDropDownStats3DViewAngle
        function DViewDropDownStats3DViewAngleValueChanged(app, event)
             ChangeProbeViewStats;
            
        end

        % Value changed function: ApplyHardwarefiltersCheckBox
        function ApplyHardwarefiltersCheckBoxValueChanged(app, event)
            HardWareFiltersChanged;
            
        end

        % Value changed function: SampleRateDropDown
        function SampleRateDropDownValueChanged(app, event)
            SampleRateChanged(str2num(event.Value(1:end-2)));
            
        end

        % Value changed function: SetAllGainEditField
        function SetAllGainEditFieldValueChanged(app, event)
            value = app.SetAllGainEditField.Value;
            SetAllDetGains(value);
        end

        % Value changed function: SetAllPowersEditField
        function SetAllPowersEditFieldValueChanged(app, event)
            value = app.SetAllPowersEditField.Value;
            SetAllLaserPwr(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BrainRecordIRUIFigure = uifigure('Visible', 'off');
            app.BrainRecordIRUIFigure.Name = 'Brain RecordIR';
            app.BrainRecordIRUIFigure.CloseRequestFcn = createCallbackFcn(app, @BrainRecordIRUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.BrainRecordIRUIFigure);
            app.FileMenu.Text = 'File';
            app.RegisterNewSubjectMenu = uimenu(app.FileMenu);
            app.RegisterNewSubjectMenu.MenuSelectedFcn = createCallbackFcn(app, @RegisterNewSubjectMenuSelected, true);
            app.RegisterNewSubjectMenu.Text = 'Register New Subject';
            app.LoadSavedDataMenu = uimenu(app.FileMenu);
            app.LoadSavedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSavedDataMenuSelected, true);
            app.LoadSavedDataMenu.Text = 'Load Saved Data';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.DataQualityMenu = uimenu(app.BrainRecordIRUIFigure);
            app.DataQualityMenu.Text = 'Data Quality';
            app.ShowQualityMenu = uimenu(app.DataQualityMenu);
            app.ShowQualityMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowQualityMenuSelected, true);
            app.ShowQualityMenu.Text = 'Show Quality';
            app.QuickSetupMenu = uimenu(app.DataQualityMenu);
            app.QuickSetupMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickSetupMenuSelected, true);
            app.QuickSetupMenu.Text = 'Quick Setup';
            app.RegisterProbe = uimenu(app.BrainRecordIRUIFigure);
            app.RegisterProbe.Text = 'Register Probe';
            app.LaunchPheobeMenu = uimenu(app.RegisterProbe);
            app.LaunchPheobeMenu.Text = 'Launch Pheobe';
            app.HelpMenu = uimenu(app.BrainRecordIRUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.HelpDocumentionMenu = uimenu(app.HelpMenu);
            app.HelpDocumentionMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpDocumentionMenuSelected, true);
            app.HelpDocumentionMenu.Text = 'Help Documention';
            app.SDGPlotWindow = uiaxes(app.BrainRecordIRUIFigure);
            app.SDGPlotWindow.XLim = [0 1];
            app.SDGPlotWindow.YLim = [0 1];
            app.SDGPlotWindow.XTick = [];
            app.SDGPlotWindow.YTick = [];
            app.TabViewGrou = uitabgroup(app.BrainRecordIRUIFigure);
            app.ResearchViewTab = uitab(app.TabViewGrou);
            app.ResearchViewTab.Title = 'Research View';
            app.MainPlotWindow = uiaxes(app.ResearchViewTab);
            xlabel(app.MainPlotWindow, 'Time')
            app.Label = uilabel(app.ResearchViewTab);
            app.Label.Text = '';
            app.SelectDisplayType = uidropdown(app.ResearchViewTab);
            app.SelectDisplayType.Items = {'Raw 690nm', 'Raw 830nm', '\deltaOD 690nm', '\deltaOD 830nm', 'HbO_2', 'HbR'};
            app.SelectDisplayType.ValueChangedFcn = createCallbackFcn(app, @SelectDisplayTypeValueChanged, true);
            app.SelectDisplayType.Value = 'Raw 690nm';
            app.AquistionControlsPanel = uipanel(app.ResearchViewTab);
            app.AquistionControlsPanel.Title = 'Aquistion Controls';
            app.Progressbar = uiaxes(app.AquistionControlsPanel);
            app.Progressbar.XTick = [];
            app.Progressbar.YTick = [];
            app.StartButton = uibutton(app.AquistionControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SourcesLabel = uilabel(app.AquistionControlsPanel);
            app.SourcesLabel.Text = 'Sources';
            app.SourcesLamp = uilamp(app.AquistionControlsPanel);
            app.SwitchSourcesOnOff = uiswitch(app.AquistionControlsPanel, 'rocker');
            app.SwitchSourcesOnOff.ValueChangedFcn = createCallbackFcn(app, @SwitchSourcesOnOffValueChanged, true);
            app.SwitchSourcesOnOff.Tooltip = {'Turns all the light sources on/off'};
            app.MarkEventButton = uibutton(app.AquistionControlsPanel, 'push');
            app.MarkEventButton.ButtonPushedFcn = createCallbackFcn(app, @MarkEventButton_2Pushed, true);
            app.MarkEventButton.Tooltip = {'Button to mark task events'};
            app.MarkEventButton.Text = 'Mark Event';
            app.AddCommentButton = uibutton(app.AquistionControlsPanel, 'push');
            app.AddCommentButton.ButtonPushedFcn = createCallbackFcn(app, @AddCommentButtonPushed, true);
            app.AddCommentButton.Tooltip = {'Button to add user comment about study (time-stamped)'};
            app.AddCommentButton.Text = 'Add Comment';
            app.WindowDataEditField = uieditfield(app.ResearchViewTab, 'numeric');
            app.WindowDataEditField.LowerLimitInclusive = 'off';
            app.WindowDataEditField.Limits = [0 Inf];
            app.WindowDataEditField.Value = 20;
            app.WindowDataCheckBox = uicheckbox(app.ResearchViewTab);
            app.WindowDataCheckBox.Text = 'Window Data';
            app.AutoscaleYaxisCheckBox = uicheckbox(app.ResearchViewTab);
            app.AutoscaleYaxisCheckBox.Text = 'Auto-scale Y-axis';
            app.AutoscaleYaxisCheckBox.Value = true;
            app.Raw690nmunfilteredLabel = uilabel(app.ResearchViewTab);
            app.Raw690nmunfilteredLabel.Text = 'Raw 690nm [unfiltered]';
            app.ClinicalViewTab = uitab(app.TabViewGrou);
            app.ClinicalViewTab.Title = 'Clinical View';
            app.UIAxesClinicalView = uiaxes(app.ClinicalViewTab);
            xlabel(app.UIAxesClinicalView, 'Time (s)')
            app.ClinicalImageAxis = uiaxes(app.ClinicalViewTab);
            app.ClinicalImageAxis.XTick = [];
            app.ClinicalImageAxis.YTick = [];
            app.RightSO2EditFieldLabel = uilabel(app.ClinicalViewTab);
            app.RightSO2EditFieldLabel.Text = {'Right '; 'SO2'};
            app.RightSO2EditField = uieditfield(app.ClinicalViewTab, 'numeric');
            app.RightSO2EditField.Limits = [0 100];
            app.RightSO2EditField.Editable = 'off';
            app.RightSO2EditField.Value = 70;
            app.LeftSO2EditFieldLabel = uilabel(app.ClinicalViewTab);
            app.LeftSO2EditFieldLabel.Text = {'Left '; 'SO2'};
            app.LeftSO2EditField = uieditfield(app.ClinicalViewTab, 'numeric');
            app.LeftSO2EditField.Limits = [0 100];
            app.LeftSO2EditField.Editable = 'off';
            app.LeftSO2EditField.Value = 70;
            app.HeartRateEditFieldLabel = uilabel(app.ClinicalViewTab);
            app.HeartRateEditFieldLabel.Text = {'Heart'; 'Rate'};
            app.HeartRateEditField = uieditfield(app.ClinicalViewTab, 'numeric');
            app.HeartRateEditField.Limits = [0 100];
            app.HeartRateEditField.Editable = 'off';
            app.DefineROIsButton = uibutton(app.ClinicalViewTab, 'push');
            app.DefineROIsButton.Text = 'Define ROIs';
            app.StatisticsViewTab = uitab(app.TabViewGrou);
            app.StatisticsViewTab.Title = 'Statistics View';
            app.UIAxesStatsViewPlot = uiaxes(app.StatisticsViewTab);
            title(app.UIAxesStatsViewPlot, 'Statistical Map: Item 1 [HbO_2]')
            app.UIAxesStatsViewPlot.XTick = [];
            app.UIAxesStatsViewPlot.YTick = [];
            app.UIAxes = uiaxes(app.StatisticsViewTab);
            title(app.UIAxes, 'Hemodynamic Response')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Hemoglobin')
            app.ListBoxWhichContrast = uilistbox(app.StatisticsViewTab);
            app.ListBoxWhichContrast.ValueChangedFcn = createCallbackFcn(app, @ListBoxWhichContrastValueChanged, true);
            app.ThreshholdEditFieldLabel = uilabel(app.StatisticsViewTab);
            app.ThreshholdEditFieldLabel.Text = 'Threshhold';
            app.ThreshholdEditField = uieditfield(app.StatisticsViewTab, 'numeric');
            app.ThreshholdEditField.Limits = [0 1];
            app.ThreshholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshholdEditFieldValueChanged, true);
            app.ThreshholdEditField.Value = 0.05;
            app.DropDownStatsPorQ = uidropdown(app.StatisticsViewTab);
            app.DropDownStatsPorQ.Items = {'p<', 'q<'};
            app.DropDownStatsPorQ.ValueChangedFcn = createCallbackFcn(app, @DropDownStatsPorQValueChanged, true);
            app.DropDownStatsPorQ.Value = 'p<';
            app.DrawModeDropDownLabel_2 = uilabel(app.StatisticsViewTab);
            app.DrawModeDropDownLabel_2.Text = 'Draw Mode';
            app.DrawMode4StatsView = uidropdown(app.StatisticsViewTab);
            app.DrawMode4StatsView.Items = {'2D HbO2', '2D HbR', '10-20 HbO2', '10-20 HbR', '3D HbO2', '3D HbR', 'HRF time-course'};
            app.DrawMode4StatsView.ValueChangedFcn = createCallbackFcn(app, @DrawMode4StatsViewValueChanged, true);
            app.DrawMode4StatsView.Value = '2D HbO2';
            app.DViewDropDownLabel = uilabel(app.StatisticsViewTab);
            app.DViewDropDownLabel.Enable = 'off';
            app.DViewDropDownLabel.Text = '3D View';
            app.DViewDropDownStats3DViewAngle = uidropdown(app.StatisticsViewTab);
            app.DViewDropDownStats3DViewAngle.Items = {'Frontal', 'Superior', 'Occipital', 'Left Temporal', 'Right Temporal'};
            app.DViewDropDownStats3DViewAngle.ValueChangedFcn = createCallbackFcn(app, @DViewDropDownStats3DViewAngleValueChanged, true);
            app.DViewDropDownStats3DViewAngle.Enable = 'off';
            app.DViewDropDownStats3DViewAngle.Value = 'Frontal';
            app.DrawModeDropDownLabel = uilabel(app.BrainRecordIRUIFigure);
            app.DrawModeDropDownLabel.Text = 'Draw Mode';
            app.DrawMode4Probe = uidropdown(app.BrainRecordIRUIFigure);
            app.DrawMode4Probe.Items = {'Flat View', '10-20 View', '3D View'};
            app.DrawMode4Probe.ValueChangedFcn = createCallbackFcn(app, @DrawMode4ProbeValueChanged, true);
            app.DrawMode4Probe.Enable = 'off';
            app.DrawMode4Probe.Value = 'Flat View';
            app.TabGroupControls = uitabgroup(app.BrainRecordIRUIFigure);
            app.DetectorsTab = uitab(app.TabGroupControls);
            app.DetectorsTab.Title = 'Detectors';
            app.AutoGainButton = uibutton(app.DetectorsTab, 'push');
            app.AutoGainButton.ButtonPushedFcn = createCallbackFcn(app, @AutoGainButtonPushed, true);
            app.AutoGainButton.Text = 'Auto-Gain';
            app.TabGroupDetectors = uitabgroup(app.DetectorsTab);
            app.Tab1_8 = uitab(app.TabGroupDetectors);
            app.Tab1_8.Title = '1-8';
            app.PanelD_1 = uipanel(app.Tab1_8);
            app.PanelD_1.Title = '25';
            app.LampD_1 = uilamp(app.PanelD_1);
            app.SliderD_1 = uislider(app.PanelD_1);
            app.SliderD_1.MajorTicks = [];
            app.EditFieldD_1 = uieditfield(app.PanelD_1, 'numeric');
            app.EditFieldD_1.Limits = [0 100];
            app.EditFieldD_1.Value = 100;
            app.PanelD_2 = uipanel(app.Tab1_8);
            app.PanelD_2.Title = '25';
            app.LampD_2 = uilamp(app.PanelD_2);
            app.SliderD_2 = uislider(app.PanelD_2);
            app.SliderD_2.MajorTicks = [];
            app.EditFieldD_2 = uieditfield(app.PanelD_2, 'numeric');
            app.EditFieldD_2.Limits = [0 100];
            app.EditFieldD_2.Value = 100;
            app.PanelD_3 = uipanel(app.Tab1_8);
            app.PanelD_3.Title = '25';
            app.LampD_3 = uilamp(app.PanelD_3);
            app.SliderD_3 = uislider(app.PanelD_3);
            app.SliderD_3.MajorTicks = [];
            app.EditFieldD_3 = uieditfield(app.PanelD_3, 'numeric');
            app.EditFieldD_3.Limits = [0 100];
            app.EditFieldD_3.Value = 100;
            app.PanelD_4 = uipanel(app.Tab1_8);
            app.PanelD_4.Title = '25';
            app.LampD_4 = uilamp(app.PanelD_4);
            app.SliderD_4 = uislider(app.PanelD_4);
            app.SliderD_4.MajorTicks = [];
            app.EditFieldD_4 = uieditfield(app.PanelD_4, 'numeric');
            app.EditFieldD_4.Limits = [0 100];
            app.EditFieldD_4.Value = 100;
            app.PanelD_5 = uipanel(app.Tab1_8);
            app.PanelD_5.Title = '25';
            app.LampD_5 = uilamp(app.PanelD_5);
            app.SliderD_5 = uislider(app.PanelD_5);
            app.SliderD_5.MajorTicks = [];
            app.EditFieldD_5 = uieditfield(app.PanelD_5, 'numeric');
            app.EditFieldD_5.Limits = [0 100];
            app.EditFieldD_5.Value = 100;
            app.PanelD_6 = uipanel(app.Tab1_8);
            app.PanelD_6.Title = '25';
            app.LampD_6 = uilamp(app.PanelD_6);
            app.SliderD_6 = uislider(app.PanelD_6);
            app.SliderD_6.MajorTicks = [];
            app.EditFieldD_6 = uieditfield(app.PanelD_6, 'numeric');
            app.EditFieldD_6.Limits = [0 100];
            app.EditFieldD_6.Value = 100;
            app.PanelD_7 = uipanel(app.Tab1_8);
            app.PanelD_7.Title = '25';
            app.LampD_7 = uilamp(app.PanelD_7);
            app.SliderD_7 = uislider(app.PanelD_7);
            app.SliderD_7.MajorTicks = [];
            app.EditFieldD_7 = uieditfield(app.PanelD_7, 'numeric');
            app.EditFieldD_7.Limits = [0 100];
            app.EditFieldD_7.Value = 100;
            app.PanelD_8 = uipanel(app.Tab1_8);
            app.PanelD_8.Title = '25';
            app.LampD_8 = uilamp(app.PanelD_8);
            app.SliderD_8 = uislider(app.PanelD_8);
            app.SliderD_8.MajorTicks = [];
            app.EditFieldD_8 = uieditfield(app.PanelD_8, 'numeric');
            app.EditFieldD_8.Limits = [0 100];
            app.EditFieldD_8.Value = 100;
            app.SetAllGainEditFieldLabel = uilabel(app.Tab1_8);
            app.SetAllGainEditFieldLabel.Text = 'Set All Gain';
            app.SetAllGainEditField = uieditfield(app.Tab1_8, 'numeric');
            app.SetAllGainEditField.Limits = [0 255];
            app.SetAllGainEditField.RoundFractionalValues = 'on';
            app.SetAllGainEditField.ValueChangedFcn = createCallbackFcn(app, @SetAllGainEditFieldValueChanged, true);
            app.Tab9_16 = uitab(app.TabGroupDetectors);
            app.Tab9_16.Title = '9-16';
            app.PanelD_9 = uipanel(app.Tab9_16);
            app.PanelD_9.Title = '25';
            app.LampD_9 = uilamp(app.PanelD_9);
            app.SliderD_9 = uislider(app.PanelD_9);
            app.SliderD_9.MajorTicks = [];
            app.EditFieldD_9 = uieditfield(app.PanelD_9, 'numeric');
            app.EditFieldD_9.Limits = [0 100];
            app.EditFieldD_9.Value = 100;
            app.PanelD_10 = uipanel(app.Tab9_16);
            app.PanelD_10.Title = '25';
            app.LampD_10 = uilamp(app.PanelD_10);
            app.SliderD_10 = uislider(app.PanelD_10);
            app.SliderD_10.MajorTicks = [];
            app.EditFieldD_10 = uieditfield(app.PanelD_10, 'numeric');
            app.EditFieldD_10.Limits = [0 100];
            app.EditFieldD_10.Value = 100;
            app.PanelD_11 = uipanel(app.Tab9_16);
            app.PanelD_11.Title = '25';
            app.LampD_11 = uilamp(app.PanelD_11);
            app.SliderD_11 = uislider(app.PanelD_11);
            app.SliderD_11.MajorTicks = [];
            app.EditFieldD_11 = uieditfield(app.PanelD_11, 'numeric');
            app.EditFieldD_11.Limits = [0 100];
            app.EditFieldD_11.Value = 100;
            app.PanelD_12 = uipanel(app.Tab9_16);
            app.PanelD_12.Title = '25';
            app.LampD_12 = uilamp(app.PanelD_12);
            app.SliderD_12 = uislider(app.PanelD_12);
            app.SliderD_12.MajorTicks = [];
            app.EditFieldD_12 = uieditfield(app.PanelD_12, 'numeric');
            app.EditFieldD_12.Limits = [0 100];
            app.EditFieldD_12.Value = 100;
            app.PanelD_13 = uipanel(app.Tab9_16);
            app.PanelD_13.Title = '25';
            app.LampD_13 = uilamp(app.PanelD_13);
            app.SliderD_13 = uislider(app.PanelD_13);
            app.SliderD_13.MajorTicks = [];
            app.EditFieldD_13 = uieditfield(app.PanelD_13, 'numeric');
            app.EditFieldD_13.Limits = [0 100];
            app.EditFieldD_13.Value = 100;
            app.PanelD_14 = uipanel(app.Tab9_16);
            app.PanelD_14.Title = '25';
            app.LampD_14 = uilamp(app.PanelD_14);
            app.SliderD_14 = uislider(app.PanelD_14);
            app.SliderD_14.MajorTicks = [];
            app.EditFieldD_14 = uieditfield(app.PanelD_14, 'numeric');
            app.EditFieldD_14.Limits = [0 100];
            app.EditFieldD_14.Value = 100;
            app.PanelD_15 = uipanel(app.Tab9_16);
            app.PanelD_15.Title = '25';
            app.LampD_15 = uilamp(app.PanelD_15);
            app.SliderD_15 = uislider(app.PanelD_15);
            app.SliderD_15.MajorTicks = [];
            app.EditFieldD_15 = uieditfield(app.PanelD_15, 'numeric');
            app.EditFieldD_15.Limits = [0 100];
            app.EditFieldD_15.Value = 100;
            app.PanelD_16 = uipanel(app.Tab9_16);
            app.PanelD_16.Title = '25';
            app.LampD_16 = uilamp(app.PanelD_16);
            app.SliderD_16 = uislider(app.PanelD_16);
            app.SliderD_16.MajorTicks = [];
            app.EditFieldD_16 = uieditfield(app.PanelD_16, 'numeric');
            app.EditFieldD_16.Limits = [0 100];
            app.EditFieldD_16.Value = 100;
            app.Tab17_24 = uitab(app.TabGroupDetectors);
            app.Tab17_24.Title = '17-24';
            app.PanelD_17 = uipanel(app.Tab17_24);
            app.PanelD_17.Title = '25';
            app.LampD_17 = uilamp(app.PanelD_17);
            app.SliderD_17 = uislider(app.PanelD_17);
            app.SliderD_17.MajorTicks = [];
            app.EditFieldD_17 = uieditfield(app.PanelD_17, 'numeric');
            app.EditFieldD_17.Limits = [0 100];
            app.EditFieldD_17.Value = 100;
            app.PanelD_18 = uipanel(app.Tab17_24);
            app.PanelD_18.Title = '25';
            app.LampD_18 = uilamp(app.PanelD_18);
            app.SliderD_18 = uislider(app.PanelD_18);
            app.SliderD_18.MajorTicks = [];
            app.EditFieldD_18 = uieditfield(app.PanelD_18, 'numeric');
            app.EditFieldD_18.Limits = [0 100];
            app.EditFieldD_18.Value = 100;
            app.PanelD_19 = uipanel(app.Tab17_24);
            app.PanelD_19.Title = '25';
            app.LampD_19 = uilamp(app.PanelD_19);
            app.SliderD_19 = uislider(app.PanelD_19);
            app.SliderD_19.MajorTicks = [];
            app.EditFieldD_19 = uieditfield(app.PanelD_19, 'numeric');
            app.EditFieldD_19.Limits = [0 100];
            app.EditFieldD_19.Value = 100;
            app.PanelD_20 = uipanel(app.Tab17_24);
            app.PanelD_20.Title = '25';
            app.LampD_20 = uilamp(app.PanelD_20);
            app.SliderD_20 = uislider(app.PanelD_20);
            app.SliderD_20.MajorTicks = [];
            app.EditFieldD_20 = uieditfield(app.PanelD_20, 'numeric');
            app.EditFieldD_20.Limits = [0 100];
            app.EditFieldD_20.Value = 100;
            app.PanelD_21 = uipanel(app.Tab17_24);
            app.PanelD_21.Title = '25';
            app.LampD_21 = uilamp(app.PanelD_21);
            app.SliderD_21 = uislider(app.PanelD_21);
            app.SliderD_21.MajorTicks = [];
            app.EditFieldD_21 = uieditfield(app.PanelD_21, 'numeric');
            app.EditFieldD_21.Limits = [0 100];
            app.EditFieldD_21.Value = 100;
            app.PanelD_22 = uipanel(app.Tab17_24);
            app.PanelD_22.Title = '25';
            app.LampD_22 = uilamp(app.PanelD_22);
            app.SliderD_22 = uislider(app.PanelD_22);
            app.SliderD_22.MajorTicks = [];
            app.EditFieldD_22 = uieditfield(app.PanelD_22, 'numeric');
            app.EditFieldD_22.Limits = [0 100];
            app.EditFieldD_22.Value = 100;
            app.PanelD_23 = uipanel(app.Tab17_24);
            app.PanelD_23.Title = '25';
            app.LampD_23 = uilamp(app.PanelD_23);
            app.SliderD_23 = uislider(app.PanelD_23);
            app.SliderD_23.MajorTicks = [];
            app.EditFieldD_23 = uieditfield(app.PanelD_23, 'numeric');
            app.EditFieldD_23.Limits = [0 100];
            app.EditFieldD_23.Value = 100;
            app.PanelD_24 = uipanel(app.Tab17_24);
            app.PanelD_24.Title = '25';
            app.LampD_24 = uilamp(app.PanelD_24);
            app.SliderD_24 = uislider(app.PanelD_24);
            app.SliderD_24.MajorTicks = [];
            app.EditFieldD_24 = uieditfield(app.PanelD_24, 'numeric');
            app.EditFieldD_24.Limits = [0 100];
            app.EditFieldD_24.Value = 100;
            app.Tab25_32 = uitab(app.TabGroupDetectors);
            app.Tab25_32.Title = '25-32';
            app.PanelD_25 = uipanel(app.Tab25_32);
            app.PanelD_25.Title = '25';
            app.LampD_25 = uilamp(app.PanelD_25);
            app.SliderD_25 = uislider(app.PanelD_25);
            app.SliderD_25.MajorTicks = [];
            app.EditFieldD_25 = uieditfield(app.PanelD_25, 'numeric');
            app.EditFieldD_25.Limits = [0 100];
            app.EditFieldD_25.Value = 100;
            app.PanelD_26 = uipanel(app.Tab25_32);
            app.PanelD_26.Title = '25';
            app.LampD_26 = uilamp(app.PanelD_26);
            app.SliderD_26 = uislider(app.PanelD_26);
            app.SliderD_26.MajorTicks = [];
            app.EditFieldD_26 = uieditfield(app.PanelD_26, 'numeric');
            app.EditFieldD_26.Limits = [0 100];
            app.EditFieldD_26.Value = 100;
            app.PanelD_27 = uipanel(app.Tab25_32);
            app.PanelD_27.Title = '25';
            app.LampD_27 = uilamp(app.PanelD_27);
            app.SliderD_27 = uislider(app.PanelD_27);
            app.SliderD_27.MajorTicks = [];
            app.EditFieldD_27 = uieditfield(app.PanelD_27, 'numeric');
            app.EditFieldD_27.Limits = [0 100];
            app.EditFieldD_27.Value = 100;
            app.PanelD_28 = uipanel(app.Tab25_32);
            app.PanelD_28.Title = '25';
            app.LampD_28 = uilamp(app.PanelD_28);
            app.SliderD_28 = uislider(app.PanelD_28);
            app.SliderD_28.MajorTicks = [];
            app.EditFieldD_28 = uieditfield(app.PanelD_28, 'numeric');
            app.EditFieldD_28.Limits = [0 100];
            app.EditFieldD_28.Value = 100;
            app.PanelD_29 = uipanel(app.Tab25_32);
            app.PanelD_29.Title = '25';
            app.LampD_29 = uilamp(app.PanelD_29);
            app.SliderD_29 = uislider(app.PanelD_29);
            app.SliderD_29.MajorTicks = [];
            app.EditFieldD_29 = uieditfield(app.PanelD_29, 'numeric');
            app.EditFieldD_29.Limits = [0 100];
            app.EditFieldD_29.Value = 100;
            app.PanelD_30 = uipanel(app.Tab25_32);
            app.PanelD_30.Title = '25';
            app.LampD_30 = uilamp(app.PanelD_30);
            app.SliderD_30 = uislider(app.PanelD_30);
            app.SliderD_30.MajorTicks = [];
            app.EditFieldD_30 = uieditfield(app.PanelD_30, 'numeric');
            app.EditFieldD_30.Limits = [0 100];
            app.EditFieldD_30.Value = 100;
            app.PanelD_31 = uipanel(app.Tab25_32);
            app.PanelD_31.Title = '25';
            app.LampD_31 = uilamp(app.PanelD_31);
            app.SliderD_31 = uislider(app.PanelD_31);
            app.SliderD_31.MajorTicks = [];
            app.EditFieldD_31 = uieditfield(app.PanelD_31, 'numeric');
            app.EditFieldD_31.Limits = [0 100];
            app.EditFieldD_31.Value = 100;
            app.PanelD_32 = uipanel(app.Tab25_32);
            app.PanelD_32.Title = '25';
            app.LampD_32 = uilamp(app.PanelD_32);
            app.SliderD_32 = uislider(app.PanelD_32);
            app.SliderD_32.MajorTicks = [];
            app.EditFieldD_32 = uieditfield(app.PanelD_32, 'numeric');
            app.EditFieldD_32.Limits = [0 100];
            app.EditFieldD_32.Value = 100;
            app.ShowNoiseMapButton = uibutton(app.DetectorsTab, 'push');
            app.ShowNoiseMapButton.ButtonPushedFcn = createCallbackFcn(app, @ShowNoiseMapButtonPushed, true);
            app.ShowNoiseMapButton.Text = 'Show Noise Map';
            app.SourcesTab = uitab(app.TabGroupControls);
            app.SourcesTab.Title = 'Sources';
            app.AutoAdjustButton = uibutton(app.SourcesTab, 'push');
            app.AutoAdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAdjustButtonPushed, true);
            app.AutoAdjustButton.Text = 'Auto-Adjust';
            app.TabGroup = uitabgroup(app.SourcesTab);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '1-4';
            app.Src1Panel = uipanel(app.Tab);
            app.Src1Panel.Title = 'Src-1';
            app.SpinnerS_1 = uispinner(app.Src1Panel);
            app.SpinnerS_2 = uispinner(app.Src1Panel);
            app.SwitchS_1 = uiswitch(app.Src1Panel, 'rocker');
            app.SwitchS_2 = uiswitch(app.Src1Panel, 'rocker');
            app.LampS_1 = uilamp(app.Src1Panel);
            app.LampS_2 = uilamp(app.Src1Panel);
            app.Src2Panel = uipanel(app.Tab);
            app.Src2Panel.Title = 'Src-2';
            app.SpinnerS_3 = uispinner(app.Src2Panel);
            app.SpinnerS_4 = uispinner(app.Src2Panel);
            app.SwitchS_3 = uiswitch(app.Src2Panel, 'rocker');
            app.SwitchS_4 = uiswitch(app.Src2Panel, 'rocker');
            app.LampS_3 = uilamp(app.Src2Panel);
            app.LampS_4 = uilamp(app.Src2Panel);
            app.Src3Panel = uipanel(app.Tab);
            app.Src3Panel.Title = 'Src-3';
            app.SpinnerS_5 = uispinner(app.Src3Panel);
            app.SpinnerS_6 = uispinner(app.Src3Panel);
            app.SwitchS_5 = uiswitch(app.Src3Panel, 'rocker');
            app.SwitchS_6 = uiswitch(app.Src3Panel, 'rocker');
            app.LampS_5 = uilamp(app.Src3Panel);
            app.LampS_6 = uilamp(app.Src3Panel);
            app.Src4Panel = uipanel(app.Tab);
            app.Src4Panel.Title = 'Src-4';
            app.SpinnerS_7 = uispinner(app.Src4Panel);
            app.SpinnerS_8 = uispinner(app.Src4Panel);
            app.SwitchS_7 = uiswitch(app.Src4Panel, 'rocker');
            app.SwitchS_8 = uiswitch(app.Src4Panel, 'rocker');
            app.LampS_7 = uilamp(app.Src4Panel);
            app.LampS_8 = uilamp(app.Src4Panel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '5-8';
            app.Src5Panel = uipanel(app.Tab_2);
            app.Src5Panel.Title = 'Src-1';
            app.SpinnerS_9 = uispinner(app.Src5Panel);
            app.SpinnerS_10 = uispinner(app.Src5Panel);
            app.SwitchS_9 = uiswitch(app.Src5Panel, 'rocker');
            app.SwitchS_10 = uiswitch(app.Src5Panel, 'rocker');
            app.LampS_9 = uilamp(app.Src5Panel);
            app.LampS_10 = uilamp(app.Src5Panel);
            app.Src6Panel = uipanel(app.Tab_2);
            app.Src6Panel.Title = 'Src-2';
            app.SpinnerS_11 = uispinner(app.Src6Panel);
            app.SpinnerS_12 = uispinner(app.Src6Panel);
            app.SwitchS_11 = uiswitch(app.Src6Panel, 'rocker');
            app.SwitchS_12 = uiswitch(app.Src6Panel, 'rocker');
            app.LampS_11 = uilamp(app.Src6Panel);
            app.LampS_12 = uilamp(app.Src6Panel);
            app.Src7Panel = uipanel(app.Tab_2);
            app.Src7Panel.Title = 'Src-3';
            app.SpinnerS_13 = uispinner(app.Src7Panel);
            app.SpinnerS_14 = uispinner(app.Src7Panel);
            app.SwitchS_13 = uiswitch(app.Src7Panel, 'rocker');
            app.SwitchS_14 = uiswitch(app.Src7Panel, 'rocker');
            app.LampS_13 = uilamp(app.Src7Panel);
            app.LampS_14 = uilamp(app.Src7Panel);
            app.Src8Panel = uipanel(app.Tab_2);
            app.Src8Panel.Title = 'Src-4';
            app.SpinnerS_15 = uispinner(app.Src8Panel);
            app.SpinnerS_16 = uispinner(app.Src8Panel);
            app.SwitchS_15 = uiswitch(app.Src8Panel, 'rocker');
            app.SwitchS_16 = uiswitch(app.Src8Panel, 'rocker');
            app.LampS_15 = uilamp(app.Src8Panel);
            app.LampS_16 = uilamp(app.Src8Panel);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '9-12';
            app.Src9Panel = uipanel(app.Tab_3);
            app.Src9Panel.Title = 'Src-1';
            app.SpinnerS_17 = uispinner(app.Src9Panel);
            app.SpinnerS_18 = uispinner(app.Src9Panel);
            app.SwitchS_17 = uiswitch(app.Src9Panel, 'rocker');
            app.SwitchS_18 = uiswitch(app.Src9Panel, 'rocker');
            app.LampS_17 = uilamp(app.Src9Panel);
            app.LampS_18 = uilamp(app.Src9Panel);
            app.Src10Panel = uipanel(app.Tab_3);
            app.Src10Panel.Title = 'Src-2';
            app.SpinnerS_19 = uispinner(app.Src10Panel);
            app.SpinnerS_20 = uispinner(app.Src10Panel);
            app.SwitchS_19 = uiswitch(app.Src10Panel, 'rocker');
            app.SwitchS_20 = uiswitch(app.Src10Panel, 'rocker');
            app.LampS_19 = uilamp(app.Src10Panel);
            app.LampS_20 = uilamp(app.Src10Panel);
            app.Src11Panel = uipanel(app.Tab_3);
            app.Src11Panel.Title = 'Src-3';
            app.SpinnerS_21 = uispinner(app.Src11Panel);
            app.SpinnerS_22 = uispinner(app.Src11Panel);
            app.SwitchS_21 = uiswitch(app.Src11Panel, 'rocker');
            app.SwitchS_22 = uiswitch(app.Src11Panel, 'rocker');
            app.LampS_21 = uilamp(app.Src11Panel);
            app.LampS_22 = uilamp(app.Src11Panel);
            app.Src12Panel = uipanel(app.Tab_3);
            app.Src12Panel.Title = 'Src-4';
            app.SpinnerS_23 = uispinner(app.Src12Panel);
            app.SpinnerS_24 = uispinner(app.Src12Panel);
            app.SwitchS_23 = uiswitch(app.Src12Panel, 'rocker');
            app.SwitchS_24 = uiswitch(app.Src12Panel, 'rocker');
            app.LampS_23 = uilamp(app.Src12Panel);
            app.LampS_24 = uilamp(app.Src12Panel);
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '13-16';
            app.Src13Panel = uipanel(app.Tab_4);
            app.Src13Panel.Title = 'Src-1';
            app.SpinnerS_25 = uispinner(app.Src13Panel);
            app.SpinnerS_26 = uispinner(app.Src13Panel);
            app.SwitchS_25 = uiswitch(app.Src13Panel, 'rocker');
            app.SwitchS_26 = uiswitch(app.Src13Panel, 'rocker');
            app.LampS_25 = uilamp(app.Src13Panel);
            app.LampS_26 = uilamp(app.Src13Panel);
            app.Src14Panel = uipanel(app.Tab_4);
            app.Src14Panel.Title = 'Src-2';
            app.SpinnerS_27 = uispinner(app.Src14Panel);
            app.SpinnerS_28 = uispinner(app.Src14Panel);
            app.SwitchS_27 = uiswitch(app.Src14Panel, 'rocker');
            app.SwitchS_28 = uiswitch(app.Src14Panel, 'rocker');
            app.LampS_27 = uilamp(app.Src14Panel);
            app.LampS_28 = uilamp(app.Src14Panel);
            app.Src15Panel = uipanel(app.Tab_4);
            app.Src15Panel.Title = 'Src-3';
            app.SpinnerS_29 = uispinner(app.Src15Panel);
            app.SpinnerS_30 = uispinner(app.Src15Panel);
            app.SwitchS_29 = uiswitch(app.Src15Panel, 'rocker');
            app.SwitchS_30 = uiswitch(app.Src15Panel, 'rocker');
            app.LampS_29 = uilamp(app.Src15Panel);
            app.LampS_30 = uilamp(app.Src15Panel);
            app.Src16Panel = uipanel(app.Tab_4);
            app.Src16Panel.Title = 'Src-4';
            app.SpinnerS_31 = uispinner(app.Src16Panel);
            app.SpinnerS_32 = uispinner(app.Src16Panel);
            app.SwitchS_31 = uiswitch(app.Src16Panel, 'rocker');
            app.SwitchS_32 = uiswitch(app.Src16Panel, 'rocker');
            app.LampS_31 = uilamp(app.Src16Panel);
            app.LampS_32 = uilamp(app.Src16Panel);
            app.LinkWavelengthsCheckBox = uicheckbox(app.SourcesTab);
            app.LinkWavelengthsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinkWavelengthsCheckBoxValueChanged, true);
            app.LinkWavelengthsCheckBox.Tooltip = {'Links the on/off state of the two wavelength sources '};
            app.LinkWavelengthsCheckBox.Text = 'Link Wavelengths';
            app.LinkWavelengthsCheckBox.Value = true;
            app.SetAllPowersEditFieldLabel = uilabel(app.SourcesTab);
            app.SetAllPowersEditFieldLabel.Text = 'Set All Powers';
            app.SetAllPowersEditField = uieditfield(app.SourcesTab, 'numeric');
            app.SetAllPowersEditField.Limits = [0 100];
            app.SetAllPowersEditField.RoundFractionalValues = 'on';
            app.SetAllPowersEditField.ValueChangedFcn = createCallbackFcn(app, @SetAllPowersEditFieldValueChanged, true);
            app.EventsTab = uitab(app.TabGroupControls);
            app.EventsTab.Title = 'Events';
            app.UITable = uitable(app.EventsTab);
            app.UITable.ColumnName = {'Name'; 'Onset'; 'Dur'; 'Amp'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Panel_9 = uipanel(app.EventsTab);
            app.MarkEventButton_2 = uibutton(app.Panel_9, 'push');
            app.MarkEventButton_2.ButtonPushedFcn = createCallbackFcn(app, @MarkEventButton_2Pushed, true);
            app.MarkEventButton_2.Text = 'Mark Event';
            app.Switch_33 = uiswitch(app.Panel_9, 'rocker');
            app.Switch_33.ValueChangedFcn = createCallbackFcn(app, @Switch_33ValueChanged, true);
            app.Switch_33.Tooltip = {'Toggle Stimulus Events On/Off'; ''};
            app.TaskDropDown = uidropdown(app.Panel_9);
            app.TaskDropDown.Editable = 'on';
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.Label_2 = uilabel(app.Panel_9);
            app.Label_2.Text = '0';
            app.NumberofEventsLabel = uilabel(app.Panel_9);
            app.NumberofEventsLabel.Text = 'Number of Events';
            app.AuxTriggersPanel = uipanel(app.EventsTab);
            app.AuxTriggersPanel.Title = 'Aux Triggers';
            app.AuxTriggersPanel.Visible = 'off';
            app.Aux1Lamp_4 = uilamp(app.AuxTriggersPanel);
            app.Aux1Lamp_5 = uilamp(app.AuxTriggersPanel);
            app.Aux1Lamp_6 = uilamp(app.AuxTriggersPanel);
            app.Aux1Lamp_7 = uilamp(app.AuxTriggersPanel);
            app.Aux1Lamp_8 = uilamp(app.AuxTriggersPanel);
            app.Aux1Lamp_9 = uilamp(app.AuxTriggersPanel);
            app.Aux1Lamp_10 = uilamp(app.AuxTriggersPanel);
            app.Aux1Lamp_11 = uilamp(app.AuxTriggersPanel);
            app.RealtimeTab = uitab(app.TabGroupControls);
            app.RealtimeTab.Title = 'Real-time';
            app.FilteringPanel = uipanel(app.RealtimeTab);
            app.FilteringPanel.Title = 'Filtering';
            app.HighPassEditFieldLabel = uilabel(app.FilteringPanel);
            app.HighPassEditFieldLabel.Text = 'High Pass';
            app.HighPassEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.HighPassEditField.Limits = [0 Inf];
            app.HighPassEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassEditFieldValueChanged, true);
            app.HighPassEditField.Tooltip = {'Removes slow drifts in the display of data'};
            app.HighPassEditField.Value = 0.016;
            app.LowPassEditFieldLabel = uilabel(app.FilteringPanel);
            app.LowPassEditFieldLabel.Text = 'Low Pass';
            app.LowPassEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.LowPassEditField.Limits = [0 Inf];
            app.LowPassEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassEditFieldValueChanged, true);
            app.LowPassEditField.Tooltip = {'Removes fast noise in the display of the data'};
            app.LowPassEditField.Value = 1;
            app.CheckBox = uicheckbox(app.FilteringPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.FilteringPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.MotionCorrectionPanel = uipanel(app.RealtimeTab);
            app.MotionCorrectionPanel.Title = 'Motion Correction';
            app.LinearModelPanel = uipanel(app.RealtimeTab);
            app.LinearModelPanel.Title = 'Linear Model';
            app.ModelDropDownLabel = uilabel(app.LinearModelPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LinearModelPanel);
            app.ModelDropDown.Items = {'Canonical', 'Block Average'};
            app.ModelDropDown.Value = 'Canonical';
            app.ApplyGLMCheckBox = uicheckbox(app.LinearModelPanel);
            app.ApplyGLMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyGLMCheckBoxValueChanged, true);
            app.ApplyGLMCheckBox.Tooltip = {'Runs real-time statistical analysis based on Barker et al 2016'};
            app.ApplyGLMCheckBox.Text = 'Apply GLM ';
            app.TuneSliderLabel = uilabel(app.LinearModelPanel);
            app.TuneSliderLabel.Text = 'Tune';
            app.TuneSlider = uislider(app.LinearModelPanel);
            app.TuneSlider.Tooltip = {'Controls the variability in the estimated response.  Small is a more convergent response.'};
            app.ApplyMotionCorrectionCheckBox = uicheckbox(app.RealtimeTab);
            app.ApplyMotionCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyMotionCorrectionCheckBoxValueChanged, true);
            app.ApplyMotionCorrectionCheckBox.Enable = 'off';
            app.ApplyMotionCorrectionCheckBox.Tooltip = {'Implements real-time motion correction based on Barker et al 2015 '};
            app.ApplyMotionCorrectionCheckBox.Text = 'Apply Motion Correction';
            app.FilesTab = uitab(app.TabGroupControls);
            app.FilesTab.Title = 'Files';
            app.Tree = uitree(app.FilesTab);
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.RecordedFilesNode = uitreenode(app.DataNode);
            app.RecordedFilesNode.Text = 'Recorded Files';
            app.LoadedFilesNode = uitreenode(app.DataNode);
            app.LoadedFilesNode.Text = 'Loaded Files';
            app.AnalyzedResultsNode = uitreenode(app.DataNode);
            app.AnalyzedResultsNode.Text = 'Analyzed Results';
            app.RunAnalysisButton = uibutton(app.FilesTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.SystemTab = uitab(app.TabGroupControls);
            app.SystemTab.Title = 'System';
            app.SampleRateDropDownLabel = uilabel(app.SystemTab);
            app.SampleRateDropDownLabel.Text = 'Sample Rate';
            app.SampleRateDropDown = uidropdown(app.SystemTab);
            app.SampleRateDropDown.Items = {'10Hz', '25Hz', '50Hz'};
            app.SampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleRateDropDownValueChanged, true);
            app.SampleRateDropDown.Tooltip = {'Sets the sample rate of the data collection device'};
            app.SampleRateDropDown.Value = '10Hz';
            app.ApplyHardwarefiltersCheckBox = uicheckbox(app.SystemTab);
            app.ApplyHardwarefiltersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyHardwarefiltersCheckBoxValueChanged, true);
            app.ApplyHardwarefiltersCheckBox.Tooltip = {'Sets the hardware lowpass filter on the Bluetooth NIRS device'};
            app.ApplyHardwarefiltersCheckBox.Text = 'Apply Hardware filters';
            app.SystemMessageTextAreaLabel = uilabel(app.SystemTab);
            app.SystemMessageTextAreaLabel.Text = 'System Message';
            app.SystemMessageTextArea = uitextarea(app.SystemTab);
            app.ConnectionEditFieldLabel = uilabel(app.SystemTab);
            app.ConnectionEditFieldLabel.Text = 'Connection';
            app.ConnectionEditField = uieditfield(app.SystemTab, 'text');
            app.ConnectionEditField.Editable = 'off';
            app.ConnectionEditField.Value = 'COM 1';
            app.NotConnectedLampLabel = uilabel(app.BrainRecordIRUIFigure);
            app.NotConnectedLampLabel.Text = 'Not Connected';
            app.NotConnectedLamp = uilamp(app.BrainRecordIRUIFigure);
            app.Battery100Label = uilabel(app.BrainRecordIRUIFigure);
            app.Battery100Label.Text = 'Battery=100%';
            % Show the figure after all components are created
            app.BrainRecordIRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainRecordIR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BrainRecordIRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BrainRecordIRUIFigure)
        end
    end
end